classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        saveButton_2                 matlab.ui.control.Button
        saveButton                   matlab.ui.control.Button
        nonSOZTextArea               matlab.ui.control.TextArea
        nonSOZTextAreaLabel          matlab.ui.control.Label
        SOZTextArea                  matlab.ui.control.TextArea
        DescendedSortingLabel        matlab.ui.control.Label
        WithictalSEEGdatapreprocessedPanel  matlab.ui.container.Panel
        DatafileselectedEditField    matlab.ui.control.EditField
        DatafileselectedLabel        matlab.ui.control.Label
        SelecttheictalSEEGdataLabel  matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        SOZlocalizingmethodDropDown  matlab.ui.control.DropDown
        SOZlocalizingmethodDropDownLabel  matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function results = SDSA_func(app, data_epi)
            sample = data_epi.fsample;
            label = data_epi.label;
            win_length = 1;
            Num_segment = size(data_epi.trial,2);
            start_time = data_epi.time{1,1}(1);
            end_time = data_epi.time{1,1}(end);
            time_label = start_time:1:(end_time-1);
            std_jibo = []; % SDSA matrix
            for i = 1:1:end_time-start_time
                window = data_epi.trial{1, 1}(:,(i-1)*win_length*sample+1:i*win_length*sample);
                std_dev = std(window,0,2);
                std_jibo(:,i) = std_dev;
            end
            mean_jibo = mean(std_jibo,1);
            ph_jibo = smooth(mean_jibo,30, 'sgolay');
            gd_jibo = gradient(ph_jibo);
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes, 'reset');
            %%% UIAxes3
            plot(app.UIAxes3, time_label,mean_jibo,'Color','#A30234','LineStyle','-');
            hold(app.UIAxes3,"on");
            plot(app.UIAxes3, time_label,ph_jibo,'Color','#00545f','LineStyle','-','LineWidth',1);
            title(app.UIAxes3,'Seizure process by SDSA');
            legend(app.UIAxes3,'orginal','smoothed');
            xlabel(app.UIAxes3,'Time/s');
            ylabel(app.UIAxes3,'SDSA');
            hold(app.UIAxes3,"off");
            %%% UIAxes3

            [~,onset_idx] = max(gd_jibo);
            onset_time = time_label(onset_idx);
            csum_jibo = sum(std_jibo(:,1:onset_idx),2);

            %%% UIAxes
            barh(app.UIAxes,csum_jibo,'EdgeColor','k','FaceColor','#0076C0');
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            ylabel(app.UIAxes,'Channels(Top: A01)');
            xlabel(app.UIAxes,'SDSA Integration');
            title(app.UIAxes,'SOZ score of each channel');
            %%% UIAxes

            [bingzao_ds,bingzao_idx] = sort(csum_jibo,'descend');
            bingzao_label = label(bingzao_idx);
            class2 = kmeans(bingzao_ds,3);
            class1 = bingzao_label(class2 == class2(1));
            lenSOZ = length(class1);

            class3 = bingzao_label(class2 == class2(1+lenSOZ));
            lenESPR = length(class3);
            class4 = bingzao_label(class2 == class2(1+lenESPR+lenSOZ));
            lenSPR = length(class4);
            %%% UIAxes2

            h = bar(app.UIAxes2,1:lenSOZ,bingzao_ds(1:lenSOZ,1));
            hold(app.UIAxes2,"on");
            h1 = bar(app.UIAxes2,lenSOZ+1:lenSOZ+lenESPR,bingzao_ds(lenSOZ+1:lenSOZ+lenESPR,1));
            h2 = bar(app.UIAxes2,lenSOZ+lenESPR+1:lenSOZ+lenESPR+lenSPR,bingzao_ds(lenSOZ+lenESPR+1:lenSOZ+lenESPR+lenSPR,1));
            app.UIAxes2.XTickLabel = [];
            app.UIAxes2.YTickLabel = [];

            for j = 1 : lenSOZ
                h(1, 1).FaceColor = 'flat';
                h(1, 1).CData(j,:) = [206,128,128]/255;
                h(1, 1).EdgeColor = 'k';
            end
            for j = 1:lenESPR
                h1(1, 1).FaceColor = 'flat';
                h1(1, 1).CData(j,:) = [171,180,125]/255;
                h1(1, 1).EdgeColor = 'k';
            end
            for j = 1:lenSPR
                h2(1, 1).FaceColor = 'flat';
                h2(1, 1).CData(j,:) = [161,197,203]/255;
                h2(1, 1).EdgeColor = 'k';
            end
            ylabel(app.UIAxes2,'SOZ score');
            xlabel(app.UIAxes2,'Channels sorted by descend SOZ score');
            title(app.UIAxes2,'SOZ clustering');
            legend(app.UIAxes2,'SOZ','early spread zones','late spread and non-spread zones');
            %%% UIAxes2
            app.SOZTextArea.Value = class1;
            app.nonSOZTextArea.Value = bingzao_label(class2 ~= class2(1));
            results = class1;
        end

        function results = LFE_func(app, data_epi)
            sample = data_epi.fsample;
            label = data_epi.label;
            win_length = 1;
            Num_segment = size(data_epi.trial,2);
            start_time = data_epi.time{1,1}(1);
            end_time = data_epi.time{1,1}(end);
            time_label = start_time:1:(end_time-1);
            std_jibo = []; % SDSA matrix
            for i = 1:1:end_time-start_time
                window = data_epi.trial{1, 1}(:,(i-1)*win_length*sample+1:i*win_length*sample);
                std_dev = std(window,0,2);
                std_jibo(:,i) = std_dev;
            end
            mean_jibo = mean(std_jibo,1);
            ph_jibo = smooth(mean_jibo,30, 'sgolay');
            gd_jibo = gradient(ph_jibo);
            [~,onset_idx] = max(gd_jibo);
            cfg              = [];
            cfg.output       = 'pow';
            cfg.channel      = 'all';
            cfg.method       = 'mtmconvol';
            cfg.taper        = 'hanning';
            cfg.toi          = [start_time : 0.5 : end_time];
            cfg.foi          = 15:1:30;
            cfg.t_ftimwin    = ones(size(cfg.foi));
            Time_freq     = ft_freqanalysis(cfg, data_epi);
            pow = Time_freq.powspctrm;
            pow2 = mean(pow,2,"omitnan");
            pow3 = squeeze(pow2);
            %             pow3 = pow3-mean(pow3(:,1:20),2,"omitnan");
            LFsum = sum(pow3(:,1:2*onset_idx-1),2,"omitnan");
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes, 'reset');
            %%% UIAxes
            barh(app.UIAxes,LFsum,'EdgeColor','k','FaceColor','#0076C0');
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            ylabel(app.UIAxes,'Channels(Top: A01)');
            xlabel(app.UIAxes,'Low Freq Energy');
            title(app.UIAxes,'SOZ score of each channel(LFE)');
            %%% UIAxes
            [LF_ds,LF_idx] = sort(LFsum,'descend');
            LF_label = label(LF_idx);
            class2 = kmeans(LF_ds,3);
            class1 = LF_label(class2 == class2(1));
            lenSOZ = length(class1);

            class3 = LF_label(class2 == class2(1+lenSOZ));
            lenESPR = length(class3);
            class4 = LF_label(class2 == class2(1+lenESPR+lenSOZ));
            lenSPR = length(class4);
            %%% UIAxes2

            h = bar(app.UIAxes2,1:lenSOZ,LF_ds(1:lenSOZ,1));
            hold(app.UIAxes2,"on");
            h1 = bar(app.UIAxes2,lenSOZ+1:lenSOZ+lenESPR,LF_ds(lenSOZ+1:lenSOZ+lenESPR,1));
            h2 = bar(app.UIAxes2,lenSOZ+lenESPR+1:lenSOZ+lenESPR+lenSPR,LF_ds(lenSOZ+lenESPR+1:lenSOZ+lenESPR+lenSPR,1));
            app.UIAxes2.XTickLabel = [];
            app.UIAxes2.YTickLabel = [];

            for j = 1 : lenSOZ
                h(1, 1).FaceColor = 'flat';
                h(1, 1).CData(j,:) = [206,128,128]/255;
                h(1, 1).EdgeColor = 'k';
            end
            for j = 1:lenESPR
                h1(1, 1).FaceColor = 'flat';
                h1(1, 1).CData(j,:) = [171,180,125]/255;
                h1(1, 1).EdgeColor = 'k';
            end
            for j = 1:lenSPR
                h2(1, 1).FaceColor = 'flat';
                h2(1, 1).CData(j,:) = [161,197,203]/255;
                h2(1, 1).EdgeColor = 'k';
            end
            ylabel(app.UIAxes2,'SOZ score');
            xlabel(app.UIAxes2,'Channels sorted by descend SOZ score');
            title(app.UIAxes2,'SOZ clustering');
            legend(app.UIAxes2,'SOZ','early spread zones','late spread and non-spread zones');
            %%% UIAxes2
            app.SOZTextArea.Value = class1;
            app.nonSOZTextArea.Value = LF_label(class2 ~= class2(1));
            results = class1;
        end
        function results = HFEI_func(app, data_epi)
            sample = data_epi.fsample;
            signal = data_epi.trial{1,1};
            label = data_epi.label;
            [N_chn,N_samp] = size(signal);
            Periods = fix((N_samp-1)/sample);
            Win_width = 1;
            step = 1;
            Win_Num = Periods-Win_width;

            E_HF = zeros(Win_Num,N_chn);
            tao = 1;
            H = 10;

            for i = 1:1:Win_Num
                window = signal(:,(i-1)*step*sample+1:i*step*sample);
                E_HF(i,:) = bandpower(window',sample,[80 120]);
            end
            E_HF = E_HF';% E_HF = zeros(N_chn,Win_Num);
            % % 以前5s为基线，做一下归一化
            BL = mean(E_HF(:,1:5),2);
            E_HF = E_HF./BL;
            % % 检测E_HF中的离群值，计算Ndi
            avg_E_HF = mean(E_HF,2);
            bzc_E_HF = std(E_HF,0,2);
            thred = avg_E_HF+10*bzc_E_HF;
            de = zeros(N_chn,Win_Num);
            Ndi = NaN(N_chn,1);

            for i =1:1:N_chn
                liqun_idx = find(E_HF(i,:)>=thred(i));
                de(i,liqun_idx) = 1;
                if isempty(liqun_idx)
                else
                    Ndi(i) = find(de(i,:),1,'first');
                end
            end
            %  肯定有全0行,这时候直接把EI值赋0就可以了
            Nd0 = min(Ndi,[],'omitnan'); % Nd0得留着，说明数组Ndi不能动
            % % 确定时间系数和能量系数
            % [jx1,TC]=sort(Ndi,'ascend','MissingPlacement','last');
            [~,jx2]=sort(Ndi,'ascend','MissingPlacement','last');
            [~,TC]=sort(jx2,'ascend'); % 神仙操作，真的牛皮
            TC(isnan(Ndi)) = N_chn;
            TC = 1./TC;
            EC = mean(E_HF(:,Nd0-1:Nd0+1),2);
            HFEI = sqrt(TC.*EC);
            results = HFEI;
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes, 'reset');
            %%% UIAxes
            barh(app.UIAxes,HFEI,'EdgeColor','k','FaceColor','#0076C0');
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            ylabel(app.UIAxes,'Channels(Top: A01)');
            xlabel(app.UIAxes,'HFEI');
            title(app.UIAxes,'HFEI of each channel');
            %%% UIAxes
            %%% test figure
            %             figure(1);
            %             barh(HFEI,'EdgeColor','k','FaceColor','#0076C0');
            %%% test figure
            [HFEI_ds,HFEI_idx] = sort(HFEI,'descend');
            bingzao_label = label(HFEI_idx);
            class2 = kmeans(HFEI_ds,3);
            class1 = bingzao_label(class2 == class2(1));
            lenSOZ = length(class1);

            class3 = bingzao_label(class2 == class2(1+lenSOZ));
            lenESPR = length(class3);
            class4 = bingzao_label(class2 == class2(1+lenESPR+lenSOZ));
            lenSPR = length(class4);
            app.DescendedSortingLabel.Text = 'HFEI_descend_channel';
            app.SOZTextArea.Value = bingzao_label;

        end

        function results = HFOs_func(app,data_epi)
            addpath("HFOFunctions\");
            parameters = [];
            parameters.chunks= 1; % Breaking data into smaller chunks
            parameters.verbose= true; % Display some information
            % % Continuous wavelet transform (CWT) parameters
            parameters.wavelet= 'morse'; % Type of wavelet
            parameters.WaveletParameters= [9,120]; % Parameters of wavelet.
            parameters.VoicesPerOctave= 16; % Voices per octave
            parameters.FrequencyLimits= [15,256]; % Frequncy range for CWT analysis
            parameters.gpu= false; % Using gpu for CWT
            % % Parameters for detecting and characterizing blobs
            parameters.compare= 'magnitude'; % Use "amplidute" or "power" for comparison
            parameters.center= 'mean'; % Use "mean" or "max" as the center
            parameters.unimodal= true; % Making sure detected blobs are unimodal
            % % Ripple detection parameters
            parameters.ripple.ecdf= 0.993; % Cutoff threshold
            parameters.ripple.range= [80,250]; % Frequency range to detect ripples
            parameters.ripple.n_cyles= 3; % Number of cycles a ripple must have at the "center" frequency
            parameters.ripple.frequency_range_th= 98/100; % Allowable difference between lowest and higher frequncy of a blob
            parameters.ripple.mean_th= 3.5; % Threshold that a blob mean/max needs to be compared to all the data at its central frequency
            parameters.ripple.ext= 500; % Upper limit allowed for comparison
            parameters.ripple.save_ecdf= false; % Saving ecdf results is unnecessary and takes large space
            % % Fast-ripple detection parameters
            parameters.fast_ripple= []; % Not interested in detecting fast ripples in this dataset.
            % % Spike detection parameters
            parameters.spike= []; % Not interested in detecting fast ripples in this dataset.
            sample = data_epi.fsample;
            label = data_epi.label;
            parameters.channels= 1:length(label); % Channels to loop through
            parameters.fs= sample; % Sampling frequency
            win_length = 1;
            start_time = data_epi.time{1,1}(1);
            end_time = data_epi.time{1,1}(end);
            std_jibo = [];
            for i = 1:1:end_time-start_time
                window = data_epi.trial{1, 1}(:,(i-1)*1*sample+1:i*1*sample);
                std_dev = std(window,0,2);
                std_jibo(:,i) = std_dev;
            end
            mean_jibo = mean(std_jibo,1);
            ph_jibo = smooth(mean_jibo,30, 'sgolay');
            gd_jibo = gradient(ph_jibo);
            [~,onset_idx] = max(gd_jibo);

            signal = data_epi.trial{1,1};
            results= HFO_detector(signal,parameters);
            HFO_rate = zeros(length(label),1);

            for c= 1:length(label)
                temp=[results.channel(c,1).ripples.center];
                if isempty(temp)
                    HFO_rate(c) = 0;
                else
                    temp=[temp.time];
                    HFO_rate(c) = length(temp(temp<onset_idx));
                end
            end

            results = HFO_rate;
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes, 'reset');
            %%% UIAxes
            barh(app.UIAxes,HFO_rate,'EdgeColor','k','FaceColor','#0076C0');
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            ylabel(app.UIAxes,'Channels(Top: A01)');
            xlabel(app.UIAxes,'HFO_rates');
            title(app.UIAxes,'HFO_rates of each channel');
            %%% UIAxes
            [HFO_ds,HFO_idx] = sort(HFO_rate,'descend');
            HFO_label = label(HFO_idx);
            class2 = kmeans(HFO_ds,3);
            class1 = HFO_label(class2 == class2(1));
            lenSOZ = length(class1);

            class3 = HFO_label(class2 == class2(1+lenSOZ));
            lenESPR = length(class3);
            class4 = HFO_label(class2 == class2(1+lenESPR+lenSOZ));
            lenSPR = length(class4);
            app.DescendedSortingLabel.Text = 'HFO_rates_descend_channel';
            app.SOZTextArea.Value = HFO_label;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*ones(50,1))
            app.UIAxes.YLim = [-1000 1000];
        end

        % Button pushed function: RunButton
        function Ictal_Pro_RUN(app, event)
            filename = app.DatafileselectedEditField.Value;
            methodname = app.SOZlocalizingmethodDropDown.Value;
            [~,~,tail] = fileparts(filename);
            switch tail
                case '.mat'
                    data = importdata(filename);
                otherwise
                    cfg = [];
                    cfg.dataset = filename;
                    cfg.continuous   = 'yes';
                    data = ft_preprocessing(cfg);
            end
            switch methodname
                case 'SDSA'
                    sth = SDSA_func(app, data);
                case 'LFE'
                    sth = LFE_func(app, data);
                case 'HFOs rate'
                    sth = HFOs_func(app, data);
                case 'HFEI'
                    sth = HFEI_func(app, data);
            end

        end

        % Button pushed function: LoadButton
        function Load_Ictal_Pre_Data(app, event)
            % Display uigetfile dialog
            filterspec = {'*.mat;*.edf;*.e;*.bdf','All SEEG Data Files'};
            [f, p] = uigetfile(filterspec);
            app.DatafileselectedEditField.Value = fullfile(p,f);
        end

        % Button pushed function: saveButton
        function save_SOZ(app, event)
            var1 = app.SOZTextArea.Value;
            filename = app.DatafileselectedEditField.Value;
            methodname = app.SOZlocalizingmethodDropDown.Value;
            [~,name,~] = fileparts(filename); 
            SOZfilename = [name '_' methodname '_SOZ_Channels.txt'];
            fid = fopen(SOZfilename,'w');
            for i = 1:length(var1)
            fprintf(fid, '%s\t', var1{i});
            fprintf(fid, '\n');
            end
            fclose(fid);
        end

        % Button pushed function: saveButton_2
        function save_nonSOZ(app, event)
            var2 = app.nonSOZTextArea.Value;
            filename = app.DatafileselectedEditField.Value;
            methodname = app.SOZlocalizingmethodDropDown.Value;
            [~,name,~] = fileparts(filename); 
            SOZfilename = [name '_' methodname '_nonSOZ_Channels.txt'];
            fid = fopen(SOZfilename,'w');
            for i = 1:length(var2)
            fprintf(fid, '%s\t', var2{i});
            fprintf(fid, '\n');
            end
            fclose(fid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SOZ scores')
            xlabel(app.UIAxes, 'SOZ scores')
            ylabel(app.UIAxes, 'Channels')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'SOZ clustering')
            xlabel(app.UIAxes2, 'Channels')
            ylabel(app.UIAxes2, 'SOZ scores')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Seizure process')
            xlabel(app.UIAxes3, 'Time/s')
            ylabel(app.UIAxes3, {''; ''})
            zlabel(app.UIAxes3, 'Z')
            app.WithictalSEEGdatapreprocessedPanel = uipanel(app.UIFigure);
            app.WithictalSEEGdatapreprocessedPanel.Title = 'With ictal SEEG data preprocessed:';
            app.SOZlocalizingmethodDropDownLabel = uilabel(app.WithictalSEEGdatapreprocessedPanel);
            app.SOZlocalizingmethodDropDownLabel.Text = 'SOZ localizing method';
            app.SOZlocalizingmethodDropDown = uidropdown(app.WithictalSEEGdatapreprocessedPanel);
            app.SOZlocalizingmethodDropDown.Items = {'SDSA', 'LFE', 'HFOs rate', 'HFEI'};
            app.SOZlocalizingmethodDropDown.Value = 'SDSA';
            app.RunButton = uibutton(app.WithictalSEEGdatapreprocessedPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Ictal_Pro_RUN, true);
            app.RunButton.Text = 'Run';
            app.LoadButton = uibutton(app.WithictalSEEGdatapreprocessedPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @Load_Ictal_Pre_Data, true);
            app.LoadButton.Text = 'Load';
            app.SelecttheictalSEEGdataLabel = uilabel(app.WithictalSEEGdatapreprocessedPanel);
            app.SelecttheictalSEEGdataLabel.Text = 'Select the ictal SEEG data';
            app.DatafileselectedLabel = uilabel(app.WithictalSEEGdatapreprocessedPanel);
            app.DatafileselectedLabel.Text = {'Data file '; 'selected'};
            app.DatafileselectedEditField = uieditfield(app.WithictalSEEGdatapreprocessedPanel, 'text');
            app.DescendedSortingLabel = uilabel(app.UIFigure);
            app.DescendedSortingLabel.Text = 'SOZ';
            app.SOZTextArea = uitextarea(app.UIFigure);
            app.nonSOZTextAreaLabel = uilabel(app.UIFigure);
            app.nonSOZTextAreaLabel.Text = 'non-SOZ';
            app.nonSOZTextArea = uitextarea(app.UIFigure);
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @save_SOZ, true);
            app.saveButton.Text = 'save';
            app.saveButton_2 = uibutton(app.UIFigure, 'push');
            app.saveButton_2.ButtonPushedFcn = createCallbackFcn(app, @save_nonSOZ, true);
            app.saveButton_2.Text = 'save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end